classdef manualOperation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        tab_connection             matlab.ui.container.Tab
        led_search_devices         matlab.ui.control.Lamp
        ConnectButton              matlab.ui.control.Button
        txt_connect_tab            matlab.ui.control.Label
        drop_devices               matlab.ui.control.DropDown
        DevicesLabel               matlab.ui.control.Label
        ConnectiontypeButtonGroup  matlab.ui.container.ButtonGroup
        BluetoothButton            matlab.ui.control.RadioButton
        USBserialButton            matlab.ui.control.RadioButton
        tab_operation              matlab.ui.container.Tab
        open_H_Button              matlab.ui.control.Button
        led_connect                matlab.ui.control.Lamp
        little_panel               matlab.ui.container.Panel
        Panel_8                    matlab.ui.container.Panel
        little_led                 matlab.ui.control.Lamp
        little_pos                 matlab.ui.control.NumericEditField
        littlepositionLabel        matlab.ui.control.Label
        Panel_7                    matlab.ui.container.Panel
        little_velPos              matlab.ui.control.Spinner
        little_velNeg              matlab.ui.control.Spinner
        little_dir                 matlab.ui.control.Slider
        mid_panel                  matlab.ui.container.Panel
        Panel_6                    matlab.ui.container.Panel
        mid_led                    matlab.ui.control.Lamp
        mid_pos                    matlab.ui.control.NumericEditField
        midpositionLabel           matlab.ui.control.Label
        Panel_5                    matlab.ui.container.Panel
        mid_velPos                 matlab.ui.control.Spinner
        mid_velNeg                 matlab.ui.control.Spinner
        mid_dir                    matlab.ui.control.Slider
        thumb_panel                matlab.ui.container.Panel
        Panel_4                    matlab.ui.container.Panel
        thumb_led                  matlab.ui.control.Lamp
        thumb_pos                  matlab.ui.control.NumericEditField
        thumbpositionLabel         matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        thumb_velPos               matlab.ui.control.Spinner
        thumb_velNeg               matlab.ui.control.Spinner
        thumb_dir                  matlab.ui.control.Slider
        idx_panel                  matlab.ui.container.Panel
        Panel_2                    matlab.ui.container.Panel
        idx_led                    matlab.ui.control.Lamp
        idx_pos                    matlab.ui.control.NumericEditField
        IndexpositionLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        idx_velPos                 matlab.ui.control.Spinner
        idx_velNeg                 matlab.ui.control.Spinner
        idx_dir                    matlab.ui.control.Slider
        close_H_Button             matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
        msj_txt                    matlab.ui.control.Label
    end


    properties (Access = private)
        device % bluetooth device, Controller object
        contNoData = 0; % counter of lost data

        params;% definitions of the hardware, struct with fields, loaded at startup
        readingTimer  % reads continuously the device

        colors = struct("gray",[0.90,0.90,0.90], ...
            "yellow", [1.00,1.00,0.07], "green", [0.00,1.00,0.00]);

        isConnected = false;

        COM_ports = [];
        bluetooth_names = [];
    end

    methods (Access = private)


        function timerFnc(app)
            % timer function executed.
            if app.isConnected
                data = app.device.read();
                if isempty(data)
                    app.contNoData = app.contNoData + 1;
                    app.msj_txt.Text = strcat('no data: ', num2str(app.contNoData));
                    % app.thumb_pos.Value = -1;
                    % app.idx_pos.Value = -1;
                    % app.mid_pos.Value = -1;
                    % app.little_pos.Value = -1;
                    return;
                else
                    app.msj_txt.Text = '';
                end

                motorId = app.params.motorIdx.thumb;
                app.thumb_pos.Value = data(end, motorId);

                motorId = app.params.motorIdx.idx;
                app.idx_pos.Value = data(end, motorId);

                motorId = app.params.motorIdx.mid;
                app.mid_pos.Value = data(end, motorId);

                motorId = app.params.motorIdx.little;
                app.little_pos.Value = data(end, motorId);
            end
        end

        function enable_connection(app, flag,color)
            %% Disables or enables the components of connection tab.
            app.ConnectButton.Enable = flag;
            app.drop_devices.Enable = flag;
            app.ConnectiontypeButtonGroup.Enable = flag;

            app.led_search_devices.Color = color;

            drawnow
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function inicio(app)
            % adding libs to path...
            addpath(genpath('.\config\'))
            addpath(genpath('.\src\'))
            addpath(genpath('.\lib\'))


            movegui(app.UIFigure, 'center');

            app.params= definitions();
            
            app.changed_comm_type([]);

            %% creating timer
            app.readingTimer = timer('ExecutionMode', 'fixedRate', ...
                'Name', 'readingProsthesis', ...
                "Period", app.params.period);
            app.readingTimer.TimerFcn = @(~, ~)app.timerFnc;
            start(app.readingTimer)
        end

        % Value changing function: idx_dir, little_dir, mid_dir, thumb_dir
        function move(app, event)
            dir = round(event.Value); %-1,0,1
            fingerId = event.Source.Tag;

            % getting vel
            if dir > 0
                vel = app.([fingerId '_velPos']).Value;
                app.([fingerId '_led']).Color = .9*[0 1 0];
            elseif dir < 0
                vel = app.([fingerId '_velNeg']).Value;
                app.([fingerId '_led']).Color = .9*[1 0 0];
            else
                app.device.stop();
                app.([fingerId '_led']).Color = .9*[1 1 1];
                return;
            end

            %% preparing message
            motorId = app.params.motorIdx.(fingerId);

            pwm = [0 0 0 0]; % speeds to be sent
            pwm(motorId) = vel*dir;

            if app.isConnected
                sent = app.device.sendAllSpeed(pwm(1), pwm(2), pwm(3), pwm(4));
                if ~sent
                    app.msj_txt.Text = 'Error sending speed';
                end
            end

        end

        % Button pushed function: STOPButton
        function STOP(app, event)
            app.device.stop();
        end

        % Close request function: UIFigure
        function exit(app, event)
            try
                stop(app.readingTimer)

                delete(app.readingTimer)
            catch ME
                disp(ME)
            end
            delete(app)

        end

        % Button pushed function: ResetButton
        function reset(app, event)
            app.device.resetEncoder();
        end

        % Selection changed function: ConnectiontypeButtonGroup
        function changed_comm_type(app, event)
            selectedButton = app.ConnectiontypeButtonGroup.SelectedObject;

            switch selectedButton.Tag
                case "bluetooth"
                    %% get bluetooth devices
                    app.txt_connect_tab.Text = ...
                        "Looking for bluetooth devices. It takes a looong time. Please, wait.";
                    app.enable_connection(false, app.colors.gray);

                    bltooth_devs = bluetoothlist();
                    app.txt_connect_tab.Text = "";

                    app.bluetooth_names = ...
                        table2array(bltooth_devs(:, "Name"));
                    app.COM_ports = [];

                    app.drop_devices.Items = app.bluetooth_names;
                    app.enable_connection(true, app.colors.yellow);

                case "usbserial"
                    %% serial
                    app.txt_connect_tab.Text = ...
                        "Looking for USB serial devices. Please, wait.";

                    app.enable_connection(false, app.colors.gray);
                    serial_devs = EnumSerialComs;
                    app.txt_connect_tab.Text = "";

                    items = {};

                    for i = serial_devs'
                        items{end + 1,1} = sprintf('COM%d |%s',i{2},i{1});
                    end

                    app.bluetooth_names = [];
                    app.COM_ports = serial_devs(:,2);

                    app.drop_devices.Items = items;
                    app.enable_connection(true, app.colors.yellow);

                otherwise
                    error("Unknown radio button")
            end
        end

        % Button pushed function: ConnectButton
        function fnc_connect(app, event)

            if isempty(app.bluetooth_names) && isempty(app.COM_ports)
                app.txt_connect_tab.Text = "[Warning] Not device selected";
                return;
            end

            name = app.drop_devices.Value;
            name = strsplit(name," |"); % to get Bl name or com port
            name = name{1};

            device_type = app.ConnectiontypeButtonGroup.SelectedObject.Tag;
            options.bluetooth = {true
                "Connecting. Trying to connect to Bluetooth device"
                name
                []
                };

            options.usbserial = {false
                "Connecting. Trying to connect to USBserial device"
                []
                name};

            options = options.(device_type);

            app.txt_connect_tab.Text = options{2};
            app.enable_connection(false, app.colors.gray);

            try
                app.device = Controller(options{1}, options{3},options{4});
                app.isConnected = true;
                
                app.txt_connect_tab.Text = "Connected!";
                app.enable_connection(true, app.colors.green);
                app.ConnectButton.Text = "Reconnect";
                app.TabGroup.SelectedTab = app.tab_operation;
                app.led_connect.Color = app.colors.green;
                
            catch ME
                disp(ME)
                app.txt_connect_tab.Text = "Could not connect.";
                app.enable_connection(true, app.colors.yellow);
                app.led_connect.Color = app.colors.yellow;
            end


        end

        % Button pushed function: close_H_Button
        function close_hand(app, event)
            app.device;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @exit, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tab_connection = uitab(app.TabGroup);
            app.tab_connection.Title = 'Connection';
            app.ConnectiontypeButtonGroup = uibuttongroup(app.tab_connection);
            app.ConnectiontypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changed_comm_type, true);
            app.ConnectiontypeButtonGroup.Title = 'Connection type';
            app.USBserialButton = uiradiobutton(app.ConnectiontypeButtonGroup);
            app.USBserialButton.Tag = 'usbserial';
            app.USBserialButton.Text = 'USB serial';
            app.USBserialButton.Value = true;
            app.BluetoothButton = uiradiobutton(app.ConnectiontypeButtonGroup);
            app.BluetoothButton.Tag = 'bluetooth';
            app.BluetoothButton.Text = 'Bluetooth';
            app.DevicesLabel = uilabel(app.tab_connection);
            app.DevicesLabel.Text = 'Devices';
            app.drop_devices = uidropdown(app.tab_connection);
            app.drop_devices.Items = {'---NA---'};
            app.drop_devices.Value = '---NA---';
            app.txt_connect_tab = uilabel(app.tab_connection);
            app.txt_connect_tab.Text = 'Messages';
            app.ConnectButton = uibutton(app.tab_connection, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @fnc_connect, true);
            app.ConnectButton.Text = 'Connect';
            app.led_search_devices = uilamp(app.tab_connection);
            app.tab_operation = uitab(app.TabGroup);
            app.tab_operation.Title = 'Fingers operation';
            app.msj_txt = uilabel(app.tab_operation);
            app.msj_txt.Text = '';
            app.STOPButton = uibutton(app.tab_operation, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOP, true);
            app.STOPButton.Text = 'STOP';
            app.ResetButton = uibutton(app.tab_operation, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.close_H_Button = uibutton(app.tab_operation, 'push');
            app.close_H_Button.ButtonPushedFcn = createCallbackFcn(app, @close_hand, true);
            app.close_H_Button.Text = 'close hand';
            app.idx_panel = uipanel(app.tab_operation);
            app.idx_panel.Title = 'Index';
            app.Panel = uipanel(app.idx_panel);
            app.idx_dir = uislider(app.Panel);
            app.idx_dir.Limits = [-1 1];
            app.idx_dir.MajorTicks = [-1 0 1];
            app.idx_dir.MajorTickLabels = {'backward', 'release', 'forward'};
            app.idx_dir.ValueChangingFcn = createCallbackFcn(app, @move, true);
            app.idx_dir.MinorTicks = [0 1];
            app.idx_dir.Tag = 'idx';
            app.idx_dir.Tooltip = {'Move the slider to move the finger.'};
            app.idx_velNeg = uispinner(app.Panel);
            app.idx_velNeg.Limits = [0 255];
            app.idx_velNeg.ValueDisplayFormat = '-%3g PWM';
            app.idx_velNeg.Tooltip = {'backwards Speed'; 'Preferable to be lower than forward speed'};
            app.idx_velNeg.Value = 100;
            app.idx_velPos = uispinner(app.Panel);
            app.idx_velPos.Limits = [0 255];
            app.idx_velPos.ValueDisplayFormat = '%3g PWM';
            app.idx_velPos.Tooltip = {'Forward speed.'; 'High to be strong enough to move the finger.'};
            app.idx_velPos.Value = 255;
            app.Panel_2 = uipanel(app.idx_panel);
            app.IndexpositionLabel = uilabel(app.Panel_2);
            app.IndexpositionLabel.Text = 'Index position';
            app.idx_pos = uieditfield(app.Panel_2, 'numeric');
            app.idx_pos.ValueDisplayFormat = '%11d';
            app.idx_pos.Editable = 'off';
            app.idx_led = uilamp(app.Panel_2);
            app.thumb_panel = uipanel(app.tab_operation);
            app.thumb_panel.Title = 'thumb';
            app.Panel_3 = uipanel(app.thumb_panel);
            app.thumb_dir = uislider(app.Panel_3);
            app.thumb_dir.Limits = [-1 1];
            app.thumb_dir.MajorTicks = [-1 0 1];
            app.thumb_dir.MajorTickLabels = {'backward', 'release', 'forward'};
            app.thumb_dir.ValueChangingFcn = createCallbackFcn(app, @move, true);
            app.thumb_dir.MinorTicks = [0 1];
            app.thumb_dir.Tag = 'thumb';
            app.thumb_dir.Tooltip = {'Move the slider to move the finger.'};
            app.thumb_velNeg = uispinner(app.Panel_3);
            app.thumb_velNeg.Limits = [0 255];
            app.thumb_velNeg.ValueDisplayFormat = '-%3g PWM';
            app.thumb_velNeg.Tooltip = {'backwards Speed'; 'Preferable to be lower than forward speed'};
            app.thumb_velNeg.Value = 50;
            app.thumb_velPos = uispinner(app.Panel_3);
            app.thumb_velPos.Limits = [0 255];
            app.thumb_velPos.ValueDisplayFormat = '%3g PWM';
            app.thumb_velPos.Tooltip = {'Forward speed.'; 'High to be strong enough to move the finger.'};
            app.thumb_velPos.Value = 255;
            app.Panel_4 = uipanel(app.thumb_panel);
            app.thumbpositionLabel = uilabel(app.Panel_4);
            app.thumbpositionLabel.Text = 'thumb position';
            app.thumb_pos = uieditfield(app.Panel_4, 'numeric');
            app.thumb_pos.ValueDisplayFormat = '%11d';
            app.thumb_pos.Editable = 'off';
            app.thumb_led = uilamp(app.Panel_4);
            app.mid_panel = uipanel(app.tab_operation);
            app.mid_panel.Title = 'mid';
            app.Panel_5 = uipanel(app.mid_panel);
            app.mid_dir = uislider(app.Panel_5);
            app.mid_dir.Limits = [-1 1];
            app.mid_dir.MajorTicks = [-1 0 1];
            app.mid_dir.MajorTickLabels = {'backward', 'release', 'forward'};
            app.mid_dir.ValueChangingFcn = createCallbackFcn(app, @move, true);
            app.mid_dir.MinorTicks = [0 1];
            app.mid_dir.Tag = 'mid';
            app.mid_dir.Tooltip = {'Move the slider to move the finger.'};
            app.mid_velNeg = uispinner(app.Panel_5);
            app.mid_velNeg.Limits = [0 255];
            app.mid_velNeg.ValueDisplayFormat = '-%3g PWM';
            app.mid_velNeg.Tooltip = {'backwards Speed'; 'Preferable to be lower than forward speed'};
            app.mid_velNeg.Value = 100;
            app.mid_velPos = uispinner(app.Panel_5);
            app.mid_velPos.Limits = [0 255];
            app.mid_velPos.ValueDisplayFormat = '%3g PWM';
            app.mid_velPos.Tooltip = {'Forward speed.'; 'High to be strong enough to move the finger.'};
            app.mid_velPos.Value = 255;
            app.Panel_6 = uipanel(app.mid_panel);
            app.midpositionLabel = uilabel(app.Panel_6);
            app.midpositionLabel.Text = 'mid position';
            app.mid_pos = uieditfield(app.Panel_6, 'numeric');
            app.mid_pos.ValueDisplayFormat = '%11d';
            app.mid_pos.Editable = 'off';
            app.mid_led = uilamp(app.Panel_6);
            app.little_panel = uipanel(app.tab_operation);
            app.little_panel.Title = 'little';
            app.Panel_7 = uipanel(app.little_panel);
            app.little_dir = uislider(app.Panel_7);
            app.little_dir.Limits = [-1 1];
            app.little_dir.MajorTicks = [-1 0 1];
            app.little_dir.MajorTickLabels = {'backward', 'release', 'forward'};
            app.little_dir.ValueChangingFcn = createCallbackFcn(app, @move, true);
            app.little_dir.MinorTicks = [0 1];
            app.little_dir.Tag = 'little';
            app.little_dir.Tooltip = {'Move the slider to move the finger.'};
            app.little_velNeg = uispinner(app.Panel_7);
            app.little_velNeg.Limits = [0 255];
            app.little_velNeg.ValueDisplayFormat = '-%3g PWM';
            app.little_velNeg.Tooltip = {'backwards Speed'; 'Preferable to be lower than forward speed'};
            app.little_velNeg.Value = 100;
            app.little_velPos = uispinner(app.Panel_7);
            app.little_velPos.Limits = [0 255];
            app.little_velPos.ValueDisplayFormat = '%3g PWM';
            app.little_velPos.Tooltip = {'Forward speed.'; 'High to be strong enough to move the finger.'};
            app.little_velPos.Value = 255;
            app.Panel_8 = uipanel(app.little_panel);
            app.littlepositionLabel = uilabel(app.Panel_8);
            app.littlepositionLabel.Text = 'little position';
            app.little_pos = uieditfield(app.Panel_8, 'numeric');
            app.little_pos.ValueDisplayFormat = '%11d';
            app.little_pos.Editable = 'off';
            app.little_led = uilamp(app.Panel_8);
            app.led_connect = uilamp(app.tab_operation);
            app.open_H_Button = uibutton(app.tab_operation, 'push');
            app.open_H_Button.Text = 'open hand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualOperation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @inicio)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end